classdef CELES_model_wizard < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CELESmodelwizardUIFigure        matlab.ui.Figure
        Title                           matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        InputTab                        matlab.ui.container.Tab
        InitialfieldPanel               matlab.ui.container.Panel
        InitialBeamFocusPanel           matlab.ui.container.Panel
        InitialFocusXEditFieldLabel     matlab.ui.control.Label
        InitialFocusXEditField          matlab.ui.control.NumericEditField
        InitialFocusYEditFieldLabel     matlab.ui.control.Label
        InitialFocusYEditField          matlab.ui.control.NumericEditField
        InitialFocusZEditFieldLabel     matlab.ui.control.Label
        InitialFocusZEditField          matlab.ui.control.NumericEditField
        InitialBeamWaistEditFieldLabel  matlab.ui.control.Label
        InitialBeamWaistEditField       matlab.ui.control.NumericEditField
        NormalizePowerButton            matlab.ui.control.Button
        InitialTypeDropDownLabel        matlab.ui.control.Label
        InitialTypeDropDown             matlab.ui.control.DropDown
        InitialPolarAngleEditFieldLabel  matlab.ui.control.Label
        InitialPolarAngleEditField      matlab.ui.control.NumericEditField
        InitialAzimuthalAngleEditFieldLabel  matlab.ui.control.Label
        InitialAzimuthalAngleEditField  matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel         matlab.ui.control.Label
        AmplitudeEditField              matlab.ui.control.NumericEditField
        PolarizationDropDownLabel       matlab.ui.control.Label
        PolarizationDropDown            matlab.ui.control.DropDown
        ParticlesPanel                  matlab.ui.container.Panel
        MonodisperseCheckBox            matlab.ui.control.CheckBox
        ParticleRadiusEditFieldLabel    matlab.ui.control.Label
        ParticleRadiusEditField         matlab.ui.control.NumericEditField
        ParticleRefractiveIndexEditFieldLabel  matlab.ui.control.Label
        ParticleRefractiveIndexEditField  matlab.ui.control.NumericEditField
        ParticleExtinctionCoefficientEditFieldLabel  matlab.ui.control.Label
        ParticleExtinctionCoefficientEditField  matlab.ui.control.NumericEditField
        ParticleshapeDropDownLabel      matlab.ui.control.Label
        ParticleshapeDropDown           matlab.ui.control.DropDown
        ParticlePositionsImportButton   matlab.ui.control.Button
        ParticleNumberEditFieldLabel    matlab.ui.control.Label
        ParticleNumberEditField         matlab.ui.control.NumericEditField
        ShiftCOMto0Button               matlab.ui.control.Button
        BackgroundrefrindexLabel        matlab.ui.control.Label
        BackgroundRefractiveIndexEditField  matlab.ui.control.NumericEditField
        VacwavelengthLabel              matlab.ui.control.Label
        WavelengthEditField             matlab.ui.control.NumericEditField
        ShowParticlesCheckBox           matlab.ui.control.CheckBox
        ShowBeamWaistCheckBox           matlab.ui.control.CheckBox
        SettingsTab                     matlab.ui.container.Tab
        lmaxLabel                       matlab.ui.control.Label
        lmaxEditField                   matlab.ui.control.NumericEditField
        ComputeOnGPUCheckBox            matlab.ui.control.CheckBox
        SolverPanel                     matlab.ui.container.Panel
        SolverTypeDropDown              matlab.ui.control.DropDown
        ToleranceEditFieldLabel         matlab.ui.control.Label
        SolverToleranceEditField        matlab.ui.control.NumericEditField
        MaxiterEditFieldLabel           matlab.ui.control.Label
        SolverMaxiterEditField          matlab.ui.control.NumericEditField
        SolverStrategyDown              matlab.ui.control.DropDown
        SolverMonitorCheckBox           matlab.ui.control.CheckBox
        PreconditionerPanel             matlab.ui.container.Panel
        PreconditionerPartitionXEditFieldLabel  matlab.ui.control.Label
        PreconditionerPartitionXEditField  matlab.ui.control.NumericEditField
        PreconditionerPartitionYEditFieldLabel  matlab.ui.control.Label
        PreconditionerPartitionYEditField  matlab.ui.control.NumericEditField
        PreconditionerPartitionZEditFieldLabel  matlab.ui.control.Label
        PreconditionerPartitionZEditField  matlab.ui.control.NumericEditField
        ShowPartitionCheckBox           matlab.ui.control.CheckBox
        PreconditionerDropDown          matlab.ui.control.DropDown
        PartitionedgesLabel             matlab.ui.control.Label
        LookupTableResolutionEditFieldLabel  matlab.ui.control.Label
        LookupTableResolutionEditField  matlab.ui.control.NumericEditField
        AngulargridPanel                matlab.ui.container.Panel
        PolarresolutiondegLabel         matlab.ui.control.Label
        PolarResolutionEditField        matlab.ui.control.NumericEditField
        AzimresolutiondegLabel          matlab.ui.control.Label
        AzimuthalResolutionEditField    matlab.ui.control.NumericEditField
        CustomPolarGridCheckBox         matlab.ui.control.CheckBox
        LoadPolarGridButton             matlab.ui.control.Button
        CustomAzimuthalGridCheckBox     matlab.ui.control.CheckBox
        LoadAzimuthalGridButton         matlab.ui.control.Button
        OutputTab                       matlab.ui.container.Tab
        FieldPanel                      matlab.ui.container.Panel
        FieldPlanePosEditFieldLabel     matlab.ui.control.Label
        FieldPlanePosEditField          matlab.ui.control.NumericEditField
        FieldDim1MinEditFieldLabel      matlab.ui.control.Label
        FieldDim1MinEditField           matlab.ui.control.NumericEditField
        FieldDim1MaxEditFieldLabel      matlab.ui.control.Label
        FieldDim1MaxEditField           matlab.ui.control.NumericEditField
        zstepLabel                      matlab.ui.control.Label
        FieldDim2StepEditField          matlab.ui.control.NumericEditField
        FieldDim2MaxEditFieldLabel      matlab.ui.control.Label
        FieldDim2MaxEditField           matlab.ui.control.NumericEditField
        FieldDim2MinEditFieldLabel      matlab.ui.control.Label
        FieldDim2MinEditField           matlab.ui.control.NumericEditField
        xstepLabel                      matlab.ui.control.Label
        FieldDim1StepEditField          matlab.ui.control.NumericEditField
        FieldPointsLoadButton           matlab.ui.control.Button
        FieldPlaneDropDown              matlab.ui.control.DropDown
        PowerEvaluationCheckBox         matlab.ui.control.CheckBox
        FieldEvaluationCheckBox         matlab.ui.control.CheckBox
        RunpostprocessingButton         matlab.ui.control.Button
        PowerPanel                      matlab.ui.container.Panel
        IncidentfluxEditFieldLabel      matlab.ui.control.Label
        IncidentFluxEditField           matlab.ui.control.NumericEditField
        ReflectedfluxEditFieldLabel     matlab.ui.control.Label
        ReflectedFluxEditField          matlab.ui.control.NumericEditField
        TransmittedfluxEditFieldLabel   matlab.ui.control.Label
        TransmittedFluxEditField        matlab.ui.control.NumericEditField
        PlotPanel                       matlab.ui.container.Panel
        ShowOutputTypeDropDown          matlab.ui.control.DropDown
        ShowOutputFieldDropDown         matlab.ui.control.DropDown
        ShowOutputPolarizationDropDown  matlab.ui.control.DropDown
        GeneratePlotButton              matlab.ui.control.Button
        GenerateAnimationButton         matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        SaveButton                      matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        ExportButton                    matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        AboutButton                     matlab.ui.control.Button
        HelpButton                      matlab.ui.control.Button
        ShowUIAxes                      matlab.ui.control.UIAxes
        ViewDropDown                    matlab.ui.control.DropDown
    end

    properties (Access = public)
        simulation
        particlePositions
        polarGrid
        azimuthalGrid
        fieldPoints
    end
    
    properties (Access = private)
        
    end
    
    methods (Access = private)
        
        function UpdateParticlePlot(app,event)
            
            cla(app.ShowUIAxes)
            text(app.ShowUIAxes,0.5,0.5,'refreshing window ...','FontSize',12,'HorizontalAlignment','center')
            drawnow;
            
            cla(app.ShowUIAxes)
            
            if app.ShowParticlesCheckBox.Value
                plot_spheres(app.ShowUIAxes,app.particlePositions,app.ParticleRadiusEditField.Value,app.ViewDropDown.Value)
            end
            if app.ShowBeamWaistCheckBox.Value
                plot_beam(app.ShowUIAxes,app)
            end
        end
        function UpdateResults(app)
            switch app.ShowOutputTypeDropDown.Value
                case {'Forward intensity','Backward intensity'}
                    app.ShowOutputPolarizationDropDown.Enable = 'on';
                case {'real Ex','real Ey','real Ez','abs E'}
                    app.ShowOutputPolarizationDropDown.Enable = 'off';
            end
            try
                app.ReflectedFluxEditField.Visible = 'on';
                app.TransmittedFluxEditField.Visible = 'on';
                if cos(app.InitialPolarAngleEditField.Value*pi/180)>0
                    app.ReflectedFluxEditField.Value = gather(app.simulation.output.totalFieldBackwardPower);
                    app.TransmittedFluxEditField.Value = gather(app.simulation.output.totalFieldForwardPower);
                else
                    app.ReflectedFluxEditField.Value = gather(app.simulation.output.totalFieldForwardPower);
                    app.TransmittedFluxEditField.Value = gather(app.simulation.output.totalFieldBackwardPower);
                end
            catch
                app.ReflectedFluxEditField.Visible = 'off';
                app.TransmittedFluxEditField.Visible = 'off';
            end
        end
        
        function UpdateIncomingPower(app)
            simulation = app2simulation(app);
            app.IncidentFluxEditField.Value = initial_power_wavebundle_normal_incidence(simulation);
        end
        
        function UpdateFieldControls(app)
            if app.FieldEvaluationCheckBox.Value
                app.FieldPlaneDropDown.Enable = 'on';
                switch app.FieldPlaneDropDown.Value
                    case {'xy-Plane','yz-Plane','xz-Plane'}
                        app.FieldPlanePosEditField.Enable = 'on';
                        app.FieldPlanePosEditFieldLabel.Enable = 'on';
                        app.FieldDim1MinEditField.Enable = 'on';
                        app.FieldDim1MinEditFieldLabel.Enable = 'on';
                        app.FieldDim2MinEditField.Enable = 'on';
                        app.FieldDim2MinEditFieldLabel.Enable = 'on';
                        app.FieldDim1MaxEditField.Enable = 'on';
                        app.FieldDim1MaxEditFieldLabel.Enable = 'on';
                        app.FieldDim2MaxEditField.Enable = 'on';
                        app.FieldDim2MaxEditFieldLabel.Enable = 'on';
                        app.FieldDim1StepEditField.Enable = 'on';
                        app.xstepLabel.Enable = 'on';
                        app.FieldDim2StepEditField.Enable = 'on';
                        app.zstepLabel.Enable = 'on';
                        app.FieldPointsLoadButton.Enable = 'off';
                    case 'Custom'
                        app.FieldPlanePosEditField.Enable = 'off';
                        app.FieldPlanePosEditFieldLabel.Enable = 'off';
                        app.FieldDim1MinEditField.Enable = 'off';
                        app.FieldDim1MinEditFieldLabel.Enable = 'off';
                        app.FieldDim2MinEditField.Enable = 'off';
                        app.FieldDim2MinEditFieldLabel.Enable = 'off';
                        app.FieldDim1MaxEditField.Enable = 'off';
                        app.FieldDim1MaxEditFieldLabel.Enable = 'off';
                        app.FieldDim2MaxEditField.Enable = 'off';
                        app.FieldDim2MaxEditFieldLabel.Enable = 'off';
                        app.FieldDim1StepEditField.Enable = 'off';
                        app.xstepLabel.Enable = 'off';
                        app.FieldDim2StepEditField.Enable = 'off';
                        app.zstepLabel.Enable = 'off';
                        app.FieldPointsLoadButton.Enable = 'on';
                end
            else
                app.FieldPlaneDropDown.Enable = 'off';
                app.FieldPlanePosEditField.Enable = 'off';
                app.FieldPlanePosEditFieldLabel.Enable = 'off';
                app.FieldDim1MinEditField.Enable = 'off';
                app.FieldDim1MinEditFieldLabel.Enable = 'off';
                app.FieldDim2MinEditField.Enable = 'off';
                app.FieldDim2MinEditFieldLabel.Enable = 'off';
                app.FieldDim1MaxEditField.Enable = 'off';
                app.FieldDim1MaxEditFieldLabel.Enable = 'off';
                app.FieldDim2MaxEditField.Enable = 'off';
                app.FieldDim2MaxEditFieldLabel.Enable = 'off';
                app.FieldDim1StepEditField.Enable = 'off';
                app.xstepLabel.Enable = 'off';
                app.FieldDim2StepEditField.Enable = 'off';
                app.zstepLabel.Enable = 'off';
                app.FieldPointsLoadButton.Enable = 'off';
            end
        end
        
        function UpdateAngularGrid(app)
            if app.CustomPolarGridCheckBox.Value
                app.PolarResolutionEditField.Enable = 'off';
                app.LoadPolarGridButton.Enable = 'on';
            else
                app.PolarResolutionEditField.Enable = 'on';
                app.LoadPolarGridButton.Enable = 'off';
            end
            if app.CustomAzimuthalGridCheckBox.Value
                app.AzimuthalResolutionEditField.Enable = 'off';
                app.LoadAzimuthalGridButton.Enable = 'on';
            else
                app.AzimuthalResolutionEditField.Enable = 'on';
                app.LoadAzimuthalGridButton.Enable = 'off';
            end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('.'))
            try
                positions = dlmread('sources/gui/default_particle_positions.dat');
            end
            app.ParticleNumberEditField.Value = length(positions(:,1));
            rmean = mean(positions);
            app.particlePositions = bsxfun(@plus,positions,-rmean);
            app.UpdateIncomingPower;
            app.UpdateAngularGrid;
            app.UpdateFieldControls;
            app.UpdateParticlePlot;
            app.simulation = app2simulation(app);
            app.UpdateResults;
        end

        % Button pushed function: AboutButton
        function AboutButtonPushed(app, event)
            msgbox({'This software has been developed as a joint project between the European Laboratory for Non-Linear Spectroscopy (LENS) and the Karlsruhe Institute of Technology (KIT) in summer 2016.','','Contact:','Amos Egel (amos.egel@kit.edu)', 'Lorenzo Pattelli (pattelli@lens.unifi.it)', 'Giacomo Mazzamuto (g.mazzamuto@gmail.com)'})
        end

        % Value changed function: AmplitudeEditField
        function AmplitudeEditFieldValueChanged(app, event)
            app.UpdateIncomingPower;
            app.UpdateParticlePlot;
        end

        % Value changed function: CustomAzimuthalGridCheckBox
        function CustomAzimuthalGridCheckBoxValueChanged(app, event)
            app.UpdateAngularGrid;
            if app.CustomAzimuthalGridCheckBox.Value
                if isempty(app.azimuthalGrid)
                    rawdata = uiimport;
                    rawdataFieldnames=fieldnames(rawdata);
                    if length(rawdataFieldnames)==1
                        azimuthalAngleData = getfield(rawdata,rawdataFieldnames{1});
                    else
                        azimuthalAngleData = rawdata.data;
                    end
                end
                if isvector(polarAngleData)
                    app.azimuthalAngles = azimuthalAngleData;
                else
                    msgbox('Error: input  must be vector')
                end
            end
        end

        % Value changed function: CustomPolarGridCheckBox
        function CustomPolarGridCheckBoxValueChanged(app, event)
            app.UpdateAngularGrid;
            if app.CustomPolarGridCheckBox.Value
                if isempty(app.polarGrid)
                    rawdata = uiimport;
                    rawdataFieldnames=fieldnames(rawdata);
                    if length(rawdataFieldnames)==1
                        polarAngleData = getfield(rawdata,rawdataFieldnames{1});
                    else
                        polarAngleData = rawdata.data;
                    end
                end
                if isvector(polarAngleData)
                    app.polarAngles = polarAngleData;
                else
                    msgbox('Error: input  must be vector')
                end
            end
        end

        % Value changed function: FieldEvaluationCheckBox
        function FieldEvaluationCheckBoxValueChanged(app, event)
            app.UpdateFieldControls;
        end

        % Value changed function: FieldPlaneDropDown
        function FieldPlaneDropDownValueChanged(app, event)
            switch app.FieldPlaneDropDown.Value
                case 'xz-Plane'
                    app.FieldPlanePosEditFieldLabel.Text = 'at y = ';
                    app.FieldDim1MinEditFieldLabel.Text = 'x min';
                    app.xstepLabel.Text = 'x step';
                    app.FieldDim1MaxEditFieldLabel.Text = 'x max';
                    app.FieldDim2MinEditFieldLabel.Text = 'z min';
                    app.zstepLabel.Text = 'z step';
                    app.FieldDim2MaxEditFieldLabel.Text = 'z max';
                case 'yz-Plane'
                    app.FieldPlanePosEditFieldLabel.Text = 'at x = ';
                    app.FieldDim1MinEditFieldLabel.Text = 'y min';
                    app.xstepLabel.Text = 'y step';
                    app.FieldDim1MaxEditFieldLabel.Text = 'y max';
                    app.FieldDim2MinEditFieldLabel.Text = 'z min';
                    app.zstepLabel.Text = 'z step';
                    app.FieldDim2MaxEditFieldLabel.Text = 'z max';
                case 'xy-Plane'
                    app.FieldPlanePosEditFieldLabel.Text = 'at z = ';
                    app.FieldDim1MinEditFieldLabel.Text = 'x min';
                    app.xstepLabel.Text = 'x step';
                    app.FieldDim1MaxEditFieldLabel.Text = 'x max';
                    app.FieldDim2MinEditFieldLabel.Text = 'y min';
                    app.zstepLabel.Text = 'y step';
                    app.FieldDim2MaxEditFieldLabel.Text = 'y max';
            end
            app.UpdateFieldControls;
        end

        % Button pushed function: FieldPointsLoadButton
        function FieldPointsLoadButtonPushed(app, event)
            rawdata = uiimport;
            rawdataFieldnames=fieldnames(rawdata);
            try %this try block catches interrupts in the file selection
                if length(rawdataFieldnames)==1
                    fieldPointData = getfield(rawdata,rawdataFieldnames{1});
                else
                    fieldPointData = rawdata.data;
                end
                if length(fieldPointData(1,:))~=3
                    msgbox('Error: Field point data must be of size N x 3 (columns are x,y,z)')
                else
                    app.fieldPoints = fieldPointData;
                end
            end
        end

        % Button pushed function: GeneratePlotButton
        function GeneratePlotButtonPushed(app, event)
            switch app.ShowOutputTypeDropDown.Value
                case {'Forward intensity','Backward intensity'}
                    figure
                    plot_intensity(app.simulation,app.ShowOutputTypeDropDown.Value,app.ShowOutputPolarizationDropDown.Value,app.ShowOutputFieldDropDown.Value)
                case {'real Ex','real Ey','real Ez','abs E'}
                    figure
                    plot_field(gca,app.simulation,app.ShowOutputTypeDropDown.Value,app.ShowOutputFieldDropDown.Value,app.ParticleRadiusEditField.Value);
                    if strcmp(app.ShowOutputTypeDropDown.Value,'abs E')
                        caxis([0,0.5]*app.AmplitudeEditField.Value)
                    else
                        caxis([-0.5,0.5]*app.AmplitudeEditField.Value)
                    end
            end
        end

        % Value changed function: InitialBeamWaistEditField
        function InitialBeamWaistEditFieldValueChanged(app, event)
            value = app.InitialBeamWaistEditField.Value;
            app.UpdateIncomingPower;
            app.UpdateParticlePlot;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            rawdata = uiimport;
            rawdataFieldnames=fieldnames(rawdata);
            if length(rawdataFieldnames)==1
                simulation = getfield(rawdata,rawdataFieldnames{1});
            else
                simulation = rawdata.data;
            end
            simulation2app(simulation,app);  % read data from simulation object and write into uicontrols
            app.UpdateIncomingPower;
            app.UpdateResults;
            app.UpdateParticlePlot;
        end

        % Button pushed function: NormalizePowerButton
        function NormalizePowerButtonPushed(app, event)
            simulation = app2simulation(app);
            pow = initial_power_wavebundle_normal_incidence(simulation);
            app.AmplitudeEditField.Value = app.AmplitudeEditField.Value/sqrt(pow);
            app.UpdateIncomingPower;
        end

        % Button pushed function: ParticlePositionsImportButton
        function ParticlePositionsImportButtonPushed(app, event)
            rawdata = uiimport;
            rawdataFieldnames=fieldnames(rawdata);
            if length(rawdataFieldnames)==1
                positionData = getfield(rawdata,rawdataFieldnames{1});
            else
                positionData = rawdata.data;
            end
            app.particlePositions = positionData;
            UpdateParticlePlot(app)
        end

        % Value changed function: PowerEvaluationCheckBox
        function PowerEvaluationCheckBoxValueChanged(app, event)
            if app.PowerEvaluationCheckBox.Value
                app.ReflectedFluxEditField.Enable = 'on';
                app.ReflectedfluxEditFieldLabel.Enable = 'on';
                app.TransmittedFluxEditField.Enable ='on';
                app.TransmittedfluxEditFieldLabel.Enable = 'on';
            else
                app.ReflectedFluxEditField.Enable = 'off';
                app.ReflectedfluxEditFieldLabel.Enable = 'off';
                app.TransmittedFluxEditField.Enable ='off';
                app.TransmittedfluxEditFieldLabel.Enable = 'off';
            end
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.simulation=app2simulation(app);
            app.simulation = app.simulation.run;
            if app.PowerEvaluationCheckBox.Value
                app.simulation = app.simulation.evaluatePower;
            end
            if app.FieldEvaluationCheckBox.Value
                app.simulation = app.simulation.evaluateFields;
            end
            app.UpdateResults;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            simulation=app2simulation(app);
            uisave('simulation','data/example_project/my_simulation');
        end

        % Button pushed function: ShiftCOMto0Button
        function ShiftCOMto0ButtonPushed(app, event)
            rmean = mean(app.particlePositions);
            app.particlePositions = bsxfun(@plus,app.particlePositions,-rmean);
            app.UpdateParticlePlot;
        end

        % Value changed function: ShowOutputFieldDropDown
        function ShowOutputFieldDropDownValueChanged(app, event)
            app.UpdateResults;
        end

        % Value changed function: ShowOutputPolarizationDropDown
        function ShowOutputPolarizationDropDownValueChanged(app, event)
            app.UpdateResults;
        end

        % Value changed function: ShowOutputTypeDropDown
        function ShowOutputTypeDropDownValueChanged(app, event)
            app.UpdateResults;
        end

        % Value changed function: InitialFocusXEditField, 
        % InitialFocusYEditField, InitialFocusZEditField, 
        % ParticleRadiusEditField, ShowBeamWaistCheckBox, 
        % ShowParticlesCheckBox, ViewDropDown
        function UpdateParticlePlotCallback(app, event)
            app.UpdateParticlePlot
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CELESmodelwizardUIFigure = uifigure;
            app.CELESmodelwizardUIFigure.Name = 'CELES model wizard';
            setAutoResize(app, app.CELESmodelwizardUIFigure, true)
            app.Title = uilabel(app.CELESmodelwizardUIFigure);
            app.Title.Text = '  CELES   Model Wizard  ';
            app.TabGroup = uitabgroup(app.CELESmodelwizardUIFigure);
            app.InputTab = uitab(app.TabGroup);
            app.InputTab.Title = 'Input';
            app.InputTab.Units = 'pixels';
            app.InitialfieldPanel = uipanel(app.InputTab);
            app.InitialfieldPanel.Title = 'Initial field';
            app.InitialBeamFocusPanel = uipanel(app.InitialfieldPanel);
            app.InitialBeamFocusPanel.Title = 'Focus';
            app.InitialFocusXEditFieldLabel = uilabel(app.InitialBeamFocusPanel);
            app.InitialFocusXEditFieldLabel.Text = 'x';
            app.InitialFocusXEditField = uieditfield(app.InitialBeamFocusPanel, 'numeric');
            app.InitialFocusXEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateParticlePlotCallback, true);
            app.InitialFocusYEditFieldLabel = uilabel(app.InitialBeamFocusPanel);
            app.InitialFocusYEditFieldLabel.Text = 'y';
            app.InitialFocusYEditField = uieditfield(app.InitialBeamFocusPanel, 'numeric');
            app.InitialFocusYEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateParticlePlotCallback, true);
            app.InitialFocusZEditFieldLabel = uilabel(app.InitialBeamFocusPanel);
            app.InitialFocusZEditFieldLabel.Text = 'z';
            app.InitialFocusZEditField = uieditfield(app.InitialBeamFocusPanel, 'numeric');
            app.InitialFocusZEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateParticlePlotCallback, true);
            app.InitialBeamWaistEditFieldLabel = uilabel(app.InitialBeamFocusPanel);
            app.InitialBeamWaistEditFieldLabel.Text = 'Width';
            app.InitialBeamWaistEditField = uieditfield(app.InitialBeamFocusPanel, 'numeric');
            app.InitialBeamWaistEditField.ValueChangedFcn = createCallbackFcn(app, @InitialBeamWaistEditFieldValueChanged, true);
            app.InitialBeamWaistEditField.Limits = [0 Inf];
            app.InitialBeamWaistEditField.Value = 800;
            app.NormalizePowerButton = uibutton(app.InitialfieldPanel, 'push');
            app.NormalizePowerButton.ButtonPushedFcn = createCallbackFcn(app, @NormalizePowerButtonPushed, true);
            app.NormalizePowerButton.Text = 'Normalize';
            app.InitialTypeDropDownLabel = uilabel(app.InitialfieldPanel);
            app.InitialTypeDropDownLabel.Text = 'Field type';
            app.InitialTypeDropDown = uidropdown(app.InitialfieldPanel);
            app.InitialTypeDropDown.Items = {'Gaussian beam'};
            app.InitialTypeDropDown.Enable = 'off';
            app.InitialTypeDropDown.Value = 'Gaussian beam';
            app.InitialPolarAngleEditFieldLabel = uilabel(app.InitialfieldPanel);
            app.InitialPolarAngleEditFieldLabel.Enable = 'off';
            app.InitialPolarAngleEditFieldLabel.Text = 'Polar angle (deg)';
            app.InitialPolarAngleEditField = uieditfield(app.InitialfieldPanel, 'numeric');
            app.InitialPolarAngleEditField.Limits = [0 180];
            app.InitialPolarAngleEditField.Enable = 'off';
            app.InitialAzimuthalAngleEditFieldLabel = uilabel(app.InitialfieldPanel);
            app.InitialAzimuthalAngleEditFieldLabel.Enable = 'off';
            app.InitialAzimuthalAngleEditFieldLabel.Text = 'Azimuthal angle (deg)';
            app.InitialAzimuthalAngleEditField = uieditfield(app.InitialfieldPanel, 'numeric');
            app.InitialAzimuthalAngleEditField.Limits = [0 360];
            app.InitialAzimuthalAngleEditField.Enable = 'off';
            app.AmplitudeEditFieldLabel = uilabel(app.InitialfieldPanel);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.InitialfieldPanel, 'numeric');
            app.AmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditFieldValueChanged, true);
            app.AmplitudeEditField.ValueDisplayFormat = '%0.2G';
            app.AmplitudeEditField.Value = 1;
            app.PolarizationDropDownLabel = uilabel(app.InitialfieldPanel);
            app.PolarizationDropDownLabel.Text = 'Polarization';
            app.PolarizationDropDown = uidropdown(app.InitialfieldPanel);
            app.PolarizationDropDown.Items = {'TE', 'TM'};
            app.PolarizationDropDown.Value = 'TE';
            app.ParticlesPanel = uipanel(app.InputTab);
            app.ParticlesPanel.Title = 'Particles';
            app.MonodisperseCheckBox = uicheckbox(app.ParticlesPanel);
            app.MonodisperseCheckBox.Enable = 'off';
            app.MonodisperseCheckBox.Text = 'Monodisperse';
            app.MonodisperseCheckBox.Value = true;
            app.ParticleRadiusEditFieldLabel = uilabel(app.ParticlesPanel);
            app.ParticleRadiusEditFieldLabel.Text = 'Radius';
            app.ParticleRadiusEditField = uieditfield(app.ParticlesPanel, 'numeric');
            app.ParticleRadiusEditField.LowerLimitInclusive = 'off';
            app.ParticleRadiusEditField.UpperLimitInclusive = 'off';
            app.ParticleRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateParticlePlotCallback, true);
            app.ParticleRadiusEditField.Limits = [0 Inf];
            app.ParticleRadiusEditField.Value = 100;
            app.ParticleRefractiveIndexEditFieldLabel = uilabel(app.ParticlesPanel);
            app.ParticleRefractiveIndexEditFieldLabel.Text = 'Refractive index n';
            app.ParticleRefractiveIndexEditField = uieditfield(app.ParticlesPanel, 'numeric');
            app.ParticleRefractiveIndexEditField.Limits = [0 Inf];
            app.ParticleRefractiveIndexEditField.Value = 2;
            app.ParticleExtinctionCoefficientEditFieldLabel = uilabel(app.ParticlesPanel);
            app.ParticleExtinctionCoefficientEditFieldLabel.Text = 'Extinction coeff. k';
            app.ParticleExtinctionCoefficientEditField = uieditfield(app.ParticlesPanel, 'numeric');
            app.ParticleExtinctionCoefficientEditField.Limits = [0 Inf];
            app.ParticleshapeDropDownLabel = uilabel(app.ParticlesPanel);
            app.ParticleshapeDropDownLabel.Text = 'Particle shape';
            app.ParticleshapeDropDown = uidropdown(app.ParticlesPanel);
            app.ParticleshapeDropDown.Items = {'Spheres'};
            app.ParticleshapeDropDown.Enable = 'off';
            app.ParticleshapeDropDown.Value = 'Spheres';
            app.ParticlePositionsImportButton = uibutton(app.ParticlesPanel, 'push');
            app.ParticlePositionsImportButton.ButtonPushedFcn = createCallbackFcn(app, @ParticlePositionsImportButtonPushed, true);
            app.ParticlePositionsImportButton.Text = 'Import positions ...';
            app.ParticleNumberEditFieldLabel = uilabel(app.ParticlesPanel);
            app.ParticleNumberEditFieldLabel.Text = 'Particle number';
            app.ParticleNumberEditField = uieditfield(app.ParticlesPanel, 'numeric');
            app.ParticleNumberEditField.Limits = [1 Inf];
            app.ParticleNumberEditField.ValueDisplayFormat = '%.0f';
            app.ParticleNumberEditField.Value = 1;
            app.ShiftCOMto0Button = uibutton(app.ParticlesPanel, 'push');
            app.ShiftCOMto0Button.ButtonPushedFcn = createCallbackFcn(app, @ShiftCOMto0ButtonPushed, true);
            app.ShiftCOMto0Button.Text = 'Shift COM to 0';
            app.BackgroundrefrindexLabel = uilabel(app.InputTab);
            app.BackgroundrefrindexLabel.Text = 'Background refr. index';
            app.BackgroundRefractiveIndexEditField = uieditfield(app.InputTab, 'numeric');
            app.BackgroundRefractiveIndexEditField.LowerLimitInclusive = 'off';
            app.BackgroundRefractiveIndexEditField.UpperLimitInclusive = 'off';
            app.BackgroundRefractiveIndexEditField.Limits = [0 Inf];
            app.BackgroundRefractiveIndexEditField.Value = 1;
            app.VacwavelengthLabel = uilabel(app.InputTab);
            app.VacwavelengthLabel.Text = 'Vac. wavelength';
            app.WavelengthEditField = uieditfield(app.InputTab, 'numeric');
            app.WavelengthEditField.Limits = [0 Inf];
            app.WavelengthEditField.Value = 550;
            app.ShowParticlesCheckBox = uicheckbox(app.InputTab);
            app.ShowParticlesCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateParticlePlotCallback, true);
            app.ShowParticlesCheckBox.Text = 'preview';
            app.ShowParticlesCheckBox.Value = true;
            app.ShowBeamWaistCheckBox = uicheckbox(app.InputTab);
            app.ShowBeamWaistCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateParticlePlotCallback, true);
            app.ShowBeamWaistCheckBox.Text = 'preview';
            app.ShowBeamWaistCheckBox.Value = true;
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.SettingsTab.Units = 'pixels';
            app.lmaxLabel = uilabel(app.SettingsTab);
            app.lmaxLabel.Text = 'lmax';
            app.lmaxEditField = uieditfield(app.SettingsTab, 'numeric');
            app.lmaxEditField.Limits = [1 Inf];
            app.lmaxEditField.ValueDisplayFormat = '%.0f';
            app.lmaxEditField.Value = 3;
            app.ComputeOnGPUCheckBox = uicheckbox(app.SettingsTab);
            app.ComputeOnGPUCheckBox.Text = 'Compute on GPU';
            app.ComputeOnGPUCheckBox.Value = true;
            app.SolverPanel = uipanel(app.SettingsTab);
            app.SolverPanel.Title = 'Solver';
            app.SolverTypeDropDown = uidropdown(app.SolverPanel);
            app.SolverTypeDropDown.Items = {'BiCGStab', 'GMRES'};
            app.SolverTypeDropDown.Value = 'BiCGStab';
            app.ToleranceEditFieldLabel = uilabel(app.SolverPanel);
            app.ToleranceEditFieldLabel.Text = 'Tolerance';
            app.SolverToleranceEditField = uieditfield(app.SolverPanel, 'numeric');
            app.SolverToleranceEditField.LowerLimitInclusive = 'off';
            app.SolverToleranceEditField.UpperLimitInclusive = 'off';
            app.SolverToleranceEditField.Limits = [0 Inf];
            app.SolverToleranceEditField.Value = 0.0001;
            app.MaxiterEditFieldLabel = uilabel(app.SolverPanel);
            app.MaxiterEditFieldLabel.Text = 'Max iter';
            app.SolverMaxiterEditField = uieditfield(app.SolverPanel, 'numeric');
            app.SolverMaxiterEditField.LowerLimitInclusive = 'off';
            app.SolverMaxiterEditField.UpperLimitInclusive = 'off';
            app.SolverMaxiterEditField.Limits = [0 Inf];
            app.SolverMaxiterEditField.ValueDisplayFormat = '%.0f';
            app.SolverMaxiterEditField.Value = 100;
            app.SolverStrategyDown = uidropdown(app.SolverPanel);
            app.SolverStrategyDown.Items = {'Iterative'};
            app.SolverStrategyDown.Enable = 'off';
            app.SolverStrategyDown.Value = 'Iterative';
            app.SolverMonitorCheckBox = uicheckbox(app.SolverPanel);
            app.SolverMonitorCheckBox.Text = 'Monitor progress';
            app.SolverMonitorCheckBox.Value = true;
            app.PreconditionerPanel = uipanel(app.SettingsTab);
            app.PreconditionerPanel.Title = 'Preconditioner';
            app.PreconditionerPartitionXEditFieldLabel = uilabel(app.PreconditionerPanel);
            app.PreconditionerPartitionXEditFieldLabel.Text = 'x';
            app.PreconditionerPartitionXEditField = uieditfield(app.PreconditionerPanel, 'numeric');
            app.PreconditionerPartitionXEditField.Value = 1500;
            app.PreconditionerPartitionYEditFieldLabel = uilabel(app.PreconditionerPanel);
            app.PreconditionerPartitionYEditFieldLabel.Text = 'y';
            app.PreconditionerPartitionYEditField = uieditfield(app.PreconditionerPanel, 'numeric');
            app.PreconditionerPartitionYEditField.Value = 1500;
            app.PreconditionerPartitionZEditFieldLabel = uilabel(app.PreconditionerPanel);
            app.PreconditionerPartitionZEditFieldLabel.Text = 'z';
            app.PreconditionerPartitionZEditField = uieditfield(app.PreconditionerPanel, 'numeric');
            app.PreconditionerPartitionZEditField.Value = 5000;
            app.ShowPartitionCheckBox = uicheckbox(app.PreconditionerPanel);
            app.ShowPartitionCheckBox.Text = 'preview';
            app.PreconditionerDropDown = uidropdown(app.PreconditionerPanel);
            app.PreconditionerDropDown.Items = {'blockdiagonal', 'none'};
            app.PreconditionerDropDown.Value = 'blockdiagonal';
            app.PartitionedgesLabel = uilabel(app.PreconditionerPanel);
            app.PartitionedgesLabel.Text = 'Partition edges';
            app.LookupTableResolutionEditFieldLabel = uilabel(app.SettingsTab);
            app.LookupTableResolutionEditFieldLabel.Text = 'Lookup resolution';
            app.LookupTableResolutionEditField = uieditfield(app.SettingsTab, 'numeric');
            app.LookupTableResolutionEditField.LowerLimitInclusive = 'off';
            app.LookupTableResolutionEditField.UpperLimitInclusive = 'off';
            app.LookupTableResolutionEditField.Limits = [0 Inf];
            app.LookupTableResolutionEditField.Value = 1;
            app.AngulargridPanel = uipanel(app.SettingsTab);
            app.AngulargridPanel.Title = 'Angular grid';
            app.PolarresolutiondegLabel = uilabel(app.AngulargridPanel);
            app.PolarresolutiondegLabel.Text = 'Polar resolution (deg)';
            app.PolarResolutionEditField = uieditfield(app.AngulargridPanel, 'numeric');
            app.PolarResolutionEditField.LowerLimitInclusive = 'off';
            app.PolarResolutionEditField.UpperLimitInclusive = 'off';
            app.PolarResolutionEditField.Limits = [0 Inf];
            app.PolarResolutionEditField.Value = 0.2;
            app.AzimresolutiondegLabel = uilabel(app.AngulargridPanel);
            app.AzimresolutiondegLabel.Text = 'Azim. resolution (deg)';
            app.AzimuthalResolutionEditField = uieditfield(app.AngulargridPanel, 'numeric');
            app.AzimuthalResolutionEditField.LowerLimitInclusive = 'off';
            app.AzimuthalResolutionEditField.UpperLimitInclusive = 'off';
            app.AzimuthalResolutionEditField.Limits = [0 Inf];
            app.AzimuthalResolutionEditField.Value = 0.5;
            app.CustomPolarGridCheckBox = uicheckbox(app.AngulargridPanel);
            app.CustomPolarGridCheckBox.ValueChangedFcn = createCallbackFcn(app, @CustomPolarGridCheckBoxValueChanged, true);
            app.CustomPolarGridCheckBox.Text = 'Custom';
            app.LoadPolarGridButton = uibutton(app.AngulargridPanel, 'push');
            app.LoadPolarGridButton.Text = 'Load';
            app.CustomAzimuthalGridCheckBox = uicheckbox(app.AngulargridPanel);
            app.CustomAzimuthalGridCheckBox.ValueChangedFcn = createCallbackFcn(app, @CustomAzimuthalGridCheckBoxValueChanged, true);
            app.CustomAzimuthalGridCheckBox.Text = 'Custom';
            app.LoadAzimuthalGridButton = uibutton(app.AngulargridPanel, 'push');
            app.LoadAzimuthalGridButton.Text = 'Load';
            app.OutputTab = uitab(app.TabGroup);
            app.OutputTab.Title = 'Output';
            app.OutputTab.Units = 'pixels';
            app.FieldPanel = uipanel(app.OutputTab);
            app.FieldPlanePosEditFieldLabel = uilabel(app.FieldPanel);
            app.FieldPlanePosEditFieldLabel.Text = 'at y =';
            app.FieldPlanePosEditField = uieditfield(app.FieldPanel, 'numeric');
            app.FieldDim1MinEditFieldLabel = uilabel(app.FieldPanel);
            app.FieldDim1MinEditFieldLabel.Text = 'x min';
            app.FieldDim1MinEditField = uieditfield(app.FieldPanel, 'numeric');
            app.FieldDim1MinEditField.Value = -3000;
            app.FieldDim1MaxEditFieldLabel = uilabel(app.FieldPanel);
            app.FieldDim1MaxEditFieldLabel.Text = 'x max';
            app.FieldDim1MaxEditField = uieditfield(app.FieldPanel, 'numeric');
            app.FieldDim1MaxEditField.Value = 3000;
            app.zstepLabel = uilabel(app.FieldPanel);
            app.zstepLabel.Text = 'z step';
            app.FieldDim2StepEditField = uieditfield(app.FieldPanel, 'numeric');
            app.FieldDim2StepEditField.Value = 50;
            app.FieldDim2MaxEditFieldLabel = uilabel(app.FieldPanel);
            app.FieldDim2MaxEditFieldLabel.Text = 'z max';
            app.FieldDim2MaxEditField = uieditfield(app.FieldPanel, 'numeric');
            app.FieldDim2MaxEditField.Value = 1500;
            app.FieldDim2MinEditFieldLabel = uilabel(app.FieldPanel);
            app.FieldDim2MinEditFieldLabel.Text = 'z min';
            app.FieldDim2MinEditField = uieditfield(app.FieldPanel, 'numeric');
            app.FieldDim2MinEditField.Value = -1500;
            app.xstepLabel = uilabel(app.FieldPanel);
            app.xstepLabel.Text = 'x step';
            app.FieldDim1StepEditField = uieditfield(app.FieldPanel, 'numeric');
            app.FieldDim1StepEditField.Value = 50;
            app.FieldPointsLoadButton = uibutton(app.FieldPanel, 'push');
            app.FieldPointsLoadButton.ButtonPushedFcn = createCallbackFcn(app, @FieldPointsLoadButtonPushed, true);
            app.FieldPointsLoadButton.Text = 'Load';
            app.FieldPlaneDropDown = uidropdown(app.FieldPanel);
            app.FieldPlaneDropDown.Items = {'xz-Plane', 'yz-Plane', 'xy-Plane'};
            app.FieldPlaneDropDown.ValueChangedFcn = createCallbackFcn(app, @FieldPlaneDropDownValueChanged, true);
            app.FieldPlaneDropDown.Value = 'xz-Plane';
            app.PowerEvaluationCheckBox = uicheckbox(app.OutputTab);
            app.PowerEvaluationCheckBox.ValueChangedFcn = createCallbackFcn(app, @PowerEvaluationCheckBoxValueChanged, true);
            app.PowerEvaluationCheckBox.Text = 'Evaluate intensity';
            app.PowerEvaluationCheckBox.Value = true;
            app.FieldEvaluationCheckBox = uicheckbox(app.OutputTab);
            app.FieldEvaluationCheckBox.ValueChangedFcn = createCallbackFcn(app, @FieldEvaluationCheckBoxValueChanged, true);
            app.FieldEvaluationCheckBox.Text = 'Evaluate field';
            app.FieldEvaluationCheckBox.Value = true;
            app.RunpostprocessingButton = uibutton(app.OutputTab, 'push');
            app.RunpostprocessingButton.Text = 'Run postprocessing';
            app.PowerPanel = uipanel(app.OutputTab);
            app.IncidentfluxEditFieldLabel = uilabel(app.PowerPanel);
            app.IncidentfluxEditFieldLabel.Text = 'Incident flux';
            app.IncidentFluxEditField = uieditfield(app.PowerPanel, 'numeric');
            app.IncidentFluxEditField.ValueDisplayFormat = '%0.2g';
            app.IncidentFluxEditField.Editable = 'off';
            app.ReflectedfluxEditFieldLabel = uilabel(app.PowerPanel);
            app.ReflectedfluxEditFieldLabel.Text = 'Reflected flux';
            app.ReflectedFluxEditField = uieditfield(app.PowerPanel, 'numeric');
            app.ReflectedFluxEditField.Enable = 'off';
            app.TransmittedfluxEditFieldLabel = uilabel(app.PowerPanel);
            app.TransmittedfluxEditFieldLabel.Text = 'Transmitted flux';
            app.TransmittedFluxEditField = uieditfield(app.PowerPanel, 'numeric');
            app.TransmittedFluxEditField.Enable = 'off';
            app.PlotPanel = uipanel(app.OutputTab);
            app.PlotPanel.Title = 'Plot';
            app.ShowOutputTypeDropDown = uidropdown(app.PlotPanel);
            app.ShowOutputTypeDropDown.Items = {'Forward intensity', 'Backward intensity', 'real Ex', 'real Ey', 'real Ez', 'abs E'};
            app.ShowOutputTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ShowOutputTypeDropDownValueChanged, true);
            app.ShowOutputTypeDropDown.Value = 'Forward intensity';
            app.ShowOutputFieldDropDown = uidropdown(app.PlotPanel);
            app.ShowOutputFieldDropDown.Items = {'Total field', 'Scattered field', 'Initial field'};
            app.ShowOutputFieldDropDown.ValueChangedFcn = createCallbackFcn(app, @ShowOutputFieldDropDownValueChanged, true);
            app.ShowOutputFieldDropDown.Value = 'Total field';
            app.ShowOutputPolarizationDropDown = uidropdown(app.PlotPanel);
            app.ShowOutputPolarizationDropDown.Items = {'TE+TM', 'TE', 'TM'};
            app.ShowOutputPolarizationDropDown.ValueChangedFcn = createCallbackFcn(app, @ShowOutputPolarizationDropDownValueChanged, true);
            app.ShowOutputPolarizationDropDown.Value = 'TE+TM';
            app.GeneratePlotButton = uibutton(app.PlotPanel, 'push');
            app.GeneratePlotButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePlotButtonPushed, true);
            app.GeneratePlotButton.Text = 'Generate plot';
            app.GenerateAnimationButton = uibutton(app.PlotPanel, 'push');
            app.GenerateAnimationButton.Enable = 'off';
            app.GenerateAnimationButton.Text = 'Generate animation';
            app.Panel = uipanel(app.CELESmodelwizardUIFigure);
            app.SaveButton = uibutton(app.Panel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save ...';
            app.LoadButton = uibutton(app.Panel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load ...';
            app.ExportButton = uibutton(app.Panel, 'push');
            app.ExportButton.Enable = 'off';
            app.ExportButton.Text = 'Export ...';
            app.RunButton = uibutton(app.Panel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.AboutButton = uibutton(app.Panel, 'push');
            app.AboutButton.ButtonPushedFcn = createCallbackFcn(app, @AboutButtonPushed, true);
            app.AboutButton.Text = 'About ...';
            app.HelpButton = uibutton(app.Panel, 'push');
            app.HelpButton.Text = 'Help';
            app.ShowUIAxes = uiaxes(app.CELESmodelwizardUIFigure);
            app.ViewDropDown = uidropdown(app.CELESmodelwizardUIFigure);
            app.ViewDropDown.Items = {'view xy', 'view xz', 'view yz'};
            app.ViewDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdateParticlePlotCallback, true);
            app.ViewDropDown.Value = 'view xz';
        end
    end

    methods (Access = public)

        % Construct app
        function app = CELES_model_wizard()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CELESmodelwizardUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CELESmodelwizardUIFigure)
        end
    end
end